classdef AUV_FEA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        PanelConsole          matlab.ui.container.Panel
        ConsoleLog            matlab.ui.control.TextArea
        PanelMesh             matlab.ui.container.Panel
        ScaleMeshField        matlab.ui.control.NumericEditField
        ScaleLabel_2          matlab.ui.control.Label
        Normals6DCheckBox     matlab.ui.control.CheckBox
        NormalsCheckBox       matlab.ui.control.CheckBox
        ImportMeshButton      matlab.ui.control.Button
        MeshGeometryButton    matlab.ui.control.Button
        HmaxField             matlab.ui.control.NumericEditField
        HminHmaxLabel_3       matlab.ui.control.Label
        HminField             matlab.ui.control.NumericEditField
        HminHmaxLabel         matlab.ui.control.Label
        ViewGeometryButton    matlab.ui.control.Button
        ScaleGeometryField    matlab.ui.control.NumericEditField
        ScaleLabel            matlab.ui.control.Label
        ImportGeometryButton  matlab.ui.control.Button
        MeshPlot              matlab.ui.control.UIAxes
        PanelRAO              matlab.ui.container.Panel
    end

    
    properties (Access = private)
        TiledLayoutRAO % a tiled layout for plotting RAO
        GeometryPath % a path to the floating geometry
        Model % model of submerged object 
        GeometryScale % value that scales geometry size
        GeometryFigureNumber % the number of handle of the geometry figure
        LastScaleGeometryValue % the previous value of ScaleGeometryField
        LastScaleMeshValue % the previous value of ScaleMeshField
        Hmin % min value for geometry mesh
        Hmax % max value for geometry mesh
        Mesh % mesh of submerged object
    end
    
    methods (Access = private)
        
        function [] = initPanelRAO(app)
            app.TiledLayoutRAO = tiledlayout(app.PanelRAO,3,1);
            app.TiledLayoutRAO.TileSpacing = 'compact';
            xlabel(app.TiledLayoutRAO,'Period')
            ylabel(app.TiledLayoutRAO,'RAO')

            ax1 = nexttile(app.TiledLayoutRAO,1);
            title(ax1,'Heave')
            hold(ax1,'on')
            ax2 = nexttile(app.TiledLayoutRAO,2);
            title(ax2,'Surge')
            hold(ax2,'on')
            ax3 = nexttile(app.TiledLayoutRAO,3);
            title(ax3,'Sway')
            hold(ax3,'on')

            linkaxes([ax1,ax2,ax3],'xy')
        end
        
        function [] = log(app,text)
            app.ConsoleLog.Value = cellstr([app.ConsoleLog.Value(:)',text]);
            uiprogressdlg(app.UIFigure)
            scroll(app.ConsoleLog, 'bottom');
        end

        function [] = updateGeometryPlot(app)
            if (ishandle(app.GeometryFigureNumber))
                figure(app.GeometryFigureNumber);
                try
                    pdegplot(app.Model,"FaceLabels","on");
                catch error                
                    log(app,error.message);
                end
            end
        end

        function [] = updateMeshPlot(app)
            trimesh(app.Mesh,'Parent',app.MeshPlot);
            hold(app.MeshPlot,"on");

            % Finds each triangle's center point and face normal vectors
            CP  = incenter(app.Mesh);
            FN = faceNormal(app.Mesh);
            
            if app.NormalsCheckBox.Value == true
                % FFR: Plotes the triangulation object's normals
                quiver3(CP(:,1),CP(:,2),CP(:,3), ...
                     FN(:,1),FN(:,2),FN(:,3),0.5,'color','r', ...
                     'Parent',app.MeshPlot);
            end

            if app.Normals6DCheckBox.Value == true
                % Creates each triangle's 6-dimensional normal vector
                [N,~] = size(CP);
                FN6 = zeros(N,3);
                FN6(:,1:3) = FN(:,:);
                FN6(:,4:6) = cross(CP(:,:),FN(:,:));
                
                % Plotes the triangulation object's rotational normals
                quiver3(app.MeshPlot,CP(:,1),CP(:,2),CP(:,3), ...
                     FN6(:,4),FN6(:,5),FN6(:,6),0.5,'color','r', ...
                     'Parent',app.MeshPlot);
            end
            hold(app.MeshPlot,"off");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            initPanelRAO(app);
            app.ConsoleLog.Value = "Welcome to AUV_FEA_Solver 1.0";
            uiprogressdlg(app.UIFigure)
            app.GeometryFigureNumber = 1;
            app.LastScaleGeometryValue = app.ScaleGeometryField.Value;
            app.LastScaleMeshValue = app.ScaleMeshField.Value;
            app.Hmin = app.HminField.Value;
            app.Hmax = app.HmaxField.Value;
            axis(app.MeshPlot, "equal");
        end

        % Button pushed function: ImportGeometryButton
        function ImportGeometryButtonPushed(app, event)
            [file,path] = uigetfile('*.stl');
            try
                app.GeometryPath = append(path,file);
                figure(app.UIFigure); % requests focus back
                app.Model = createpde();
                importGeometry(app.Model,app.GeometryPath);
                scale(app.Model.Geometry, app.LastScaleGeometryValue);
                log(app,append("Imported: ",file));
            catch error                
                log(app,error.message);
            end
        end

        % Button pushed function: ViewGeometryButton
        function ViewGeometryButtonPushed(app, event)
            figure(app.GeometryFigureNumber)
            updateGeometryPlot(app);
        end

        % Value changed function: ScaleGeometryField
        function ScaleGeometryFieldValueChanged(app, event)
            value = app.ScaleGeometryField.Value/app.LastScaleGeometryValue;
            try
                scale(app.Model.Geometry, value);
                app.LastScaleGeometryValue = value;
                updateGeometryPlot(app);
            catch
            end
        end

        % Value changed function: HmaxField
        function HmaxFieldValueChanged(app, event)
            app.Hmax = app.HmaxField.Value;
        end

        % Value changed function: HminField
        function HminFieldValueChanged(app, event)
            app.Hmin = app.HminField.Value;
        end

        % Button pushed function: MeshGeometryButton
        function MeshGeometryButtonPushed(app, event)
            try
                generateMesh(app.Model, 'GeometricOrder','linear','Hmin',app.Hmin);%,'Hmax',app.Hmax);
                
                % Makes a triangulation object of Model.Mesh's surface
                mto = triangulation(app.Model.Mesh.Elements.',app.Model.Mesh.Nodes.');                
                [T,P] = freeBoundary(mto);
                app.Mesh = triangulation(T,P);
                updateMeshPlot(app);
            catch error                
                log(app,error.message);
            end
        end

        % Value changed function: Normals6DCheckBox
        function Normals6DCheckBoxValueChanged(app, event)
            updateMeshPlot(app);
        end

        % Value changed function: NormalsCheckBox
        function NormalsCheckBoxValueChanged(app, event)
            updateMeshPlot(app);       
        end

        % Button pushed function: ImportMeshButton
        function ImportMeshButtonPushed(app, event)
            [file,path] = uigetfile('*.stl');
            try
                app.Mesh = stlread(append(path,file));
                figure(app.UIFigure); % requests focus back
                app.Mesh = triangulation(app.Mesh.ConnectivityList, ...
                    app.Mesh.Points*app.ScaleMeshField.Value);
                updateMeshPlot(app);
                log(app,append("Imported: ",file));
            catch error
                log(app,error.message);
            end
        end

        % Value changed function: ScaleMeshField
        function ScaleMeshFieldValueChanged(app, event)
            value = app.ScaleMeshField.Value/app.LastScaleMeshValue;
            try
                app.Mesh = triangulation(app.Mesh.ConnectivityList, ...
                    app.Mesh.Points*value);
                app.LastScaleMeshValue = app.ScaleMeshField.Value;
                updateMeshPlot(app);
            catch
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PanelRAO = uipanel(app.UIFigure);
            app.PanelMesh = uipanel(app.UIFigure);
            app.MeshPlot = uiaxes(app.PanelMesh);
            title(app.MeshPlot, 'Mesh Plot')
            xlabel(app.MeshPlot, 'X')
            ylabel(app.MeshPlot, 'Y')
            zlabel(app.MeshPlot, 'Z')
            app.MeshPlot.XLim = [-1 0.5];
            app.MeshPlot.YLim = [-0.5 0.5];
            app.MeshPlot.ZLim = [-0.5 0];
            app.MeshPlot.XTick = [-1 -0.5 0 0.5];
            app.MeshPlot.XTickLabel = {'-1'; '-0.5'; '0'; '0.5'};
            app.MeshPlot.YTick = [-0.5 0 0.5];
            app.MeshPlot.YTickLabel = {'-0.5'; '0'; '0.5'};
            app.MeshPlot.ZTick = [-0.5 0];
            app.MeshPlot.ZTickLabel = {'-0.5'; '0'};
            app.ImportGeometryButton = uibutton(app.PanelMesh, 'push');
            app.ImportGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @ImportGeometryButtonPushed, true);
            app.ImportGeometryButton.Text = 'Import Geometry';
            app.ScaleLabel = uilabel(app.PanelMesh);
            app.ScaleLabel.Text = 'Scale Geometry';
            app.ScaleGeometryField = uieditfield(app.PanelMesh, 'numeric');
            app.ScaleGeometryField.ValueChangedFcn = createCallbackFcn(app, @ScaleGeometryFieldValueChanged, true);
            app.ScaleGeometryField.Value = 0.0001;
            app.ViewGeometryButton = uibutton(app.PanelMesh, 'push');
            app.ViewGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @ViewGeometryButtonPushed, true);
            app.ViewGeometryButton.Text = 'View Geometry';
            app.HminHmaxLabel = uilabel(app.PanelMesh);
            app.HminHmaxLabel.Text = 'Hmin';
            app.HminField = uieditfield(app.PanelMesh, 'numeric');
            app.HminField.ValueChangedFcn = createCallbackFcn(app, @HminFieldValueChanged, true);
            app.HminField.Value = 0.01;
            app.HminHmaxLabel_3 = uilabel(app.PanelMesh);
            app.HminHmaxLabel_3.Text = 'Hmax';
            app.HmaxField = uieditfield(app.PanelMesh, 'numeric');
            app.HmaxField.ValueChangedFcn = createCallbackFcn(app, @HmaxFieldValueChanged, true);
            app.HmaxField.Value = 0.1;
            app.MeshGeometryButton = uibutton(app.PanelMesh, 'push');
            app.MeshGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @MeshGeometryButtonPushed, true);
            app.MeshGeometryButton.Text = 'Mesh Geometry';
            app.ImportMeshButton = uibutton(app.PanelMesh, 'push');
            app.ImportMeshButton.ButtonPushedFcn = createCallbackFcn(app, @ImportMeshButtonPushed, true);
            app.ImportMeshButton.Text = 'Import Mesh';
            app.NormalsCheckBox = uicheckbox(app.PanelMesh);
            app.NormalsCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalsCheckBoxValueChanged, true);
            app.NormalsCheckBox.Text = 'Show Normals';
            app.Normals6DCheckBox = uicheckbox(app.PanelMesh);
            app.Normals6DCheckBox.ValueChangedFcn = createCallbackFcn(app, @Normals6DCheckBoxValueChanged, true);
            app.Normals6DCheckBox.Text = 'Show 6D Normals';
            app.ScaleLabel_2 = uilabel(app.PanelMesh);
            app.ScaleLabel_2.Text = 'Scale Imported Mesh';
            app.ScaleMeshField = uieditfield(app.PanelMesh, 'numeric');
            app.ScaleMeshField.ValueChangedFcn = createCallbackFcn(app, @ScaleMeshFieldValueChanged, true);
            app.ScaleMeshField.Value = 0.1;
            app.PanelConsole = uipanel(app.UIFigure);
            app.ConsoleLog = uitextarea(app.PanelConsole);
            app.ConsoleLog.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AUV_FEA(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end